classdef DeBruijnGridsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        CheckE                 matlab.ui.control.CheckBox
        CheckD                 matlab.ui.control.CheckBox
        CheckC                 matlab.ui.control.CheckBox
        CheckB                 matlab.ui.control.CheckBox
        CheckA                 matlab.ui.control.CheckBox
        RedrawButton           matlab.ui.control.Button
        GridLayout2            matlab.ui.container.GridLayout
        UpperY                 matlab.ui.control.NumericEditField
        LowerY                 matlab.ui.control.NumericEditField
        UpperX                 matlab.ui.control.NumericEditField
        LowerX                 matlab.ui.control.NumericEditField
        YLimsLabel             matlab.ui.control.Label
        XLimsLabel             matlab.ui.control.Label
        OffsetE                matlab.ui.control.NumericEditField
        OffsetEEditFieldLabel  matlab.ui.control.Label
        OffsetD                matlab.ui.control.NumericEditField
        OffsetDEditFieldLabel  matlab.ui.control.Label
        OffsetC                matlab.ui.control.NumericEditField
        OffsetCEditFieldLabel  matlab.ui.control.Label
        OffsetB                matlab.ui.control.NumericEditField
        OffsetBEditFieldLabel  matlab.ui.control.Label
        OffsetA                matlab.ui.control.NumericEditField
        OffsetALabel           matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Constant)
        lineNames = containers.Map({'A', 'B', 'C', 'D', 'E'}, [1, 2, 3, 4, 5])
    end

    properties (Access = private)
        linesGroups = {}
        lineCheckboxes = []
        lineColors = [
            "0000df"
            "dfdf00"
            "ff00ff"
            "de0000"
            "00df00"
            ]
        offsetFields = []
    end
    
    methods (Access = private)

        function changeAxesLims(app)
            xLim = [app.LowerX.Value, app.UpperX.Value];
            yLim = [app.LowerY.Value, app.UpperY.Value];

            app.UIAxes.XLim = xLim;
            app.UIAxes.YLim = yLim;
        end
        
        function drawLines(app)

            clearLines(app);

            for k = 1:5
                parameters = app.linesGroupParameters(k);

                if get(app.lineCheckboxes(k), 'Value')
                    visibility = 'on';
                else
                    visibility = 'off';
                end

                lines = [];
                for i = 1: size(parameters, 1)
                    intersections = app.axesBoxIntersection(parameters(i,:));

                    if size(intersections, 1) > 1
                        xPoints = intersections(1, :);
                        yPoints = intersections(2, :);

                        lines(end + 1) = line( ...
                            app.UIAxes, ...
                            xPoints, yPoints, ...
                            'Color', DeBruijnGridsApp.html2rgb(app.lineColors(k)), ...
                            'Visible', visibility); %#ok<AGROW>
                    end
                end
                app.linesGroups{k} = lines;
            end
            
        end

        function clearLines(app)
            for k = 1 : numel(app.linesGroups)
                delete(app.linesGroups{k});
            end
            app.linesGroups = {};
        end

        function linesGroupParameters = linesGroupParameters(app, k)
            offset = app.offsetFields(k).Value;
            lineParameters = [cos(2 * pi / 5 * (k - 1)), sin(2 * pi / 5 * (k - 1)), offset];

            [from, to] = lineIntegerOffetRangeToFitArea(app, lineParameters);

            linesGroupParameters = [];
            if to >= from
                for s = from : to
                    linesGroupParameters(end + 1, :) = ...
                    [lineParameters(1:2), lineParameters(3) + s]; %#ok<AGROW>
                end
            end
        end

        function [offsetFrom, offsetTo] = lineIntegerOffetRangeToFitArea(app, lineParameters)

            xLim = [app.LowerX.Value, app.UpperX.Value];
            yLim = [app.LowerY.Value, app.UpperY.Value];
            axesCenter = [sum(xLim) / 2; sum(yLim) / 2];
            axesDiagonal = sqrt((xLim(2) - xLim(1))^2 + (yLim(2) - yLim(1))^2);

            distance = (lineParameters(1) * axesCenter(1) ...
                + lineParameters(2) * axesCenter(2) + lineParameters(3)) ...
                / sqrt(lineParameters(1)^2 + lineParameters(2)^2);

            offsetFrom = ceil(-distance - axesDiagonal);
            offsetTo = floor(-distance + axesDiagonal);
        end

        function intersections = axesBoxIntersection(app, lineParameters)
            xLim = app.UIAxes.XLim;
            yLim = app.UIAxes.YLim;

            xMin = xLim(1);
            xMax = xLim(2);
            yMin = yLim(1);
            yMax = yLim(2);


            xBottomIntersection = DeBruijnGridsApp.linesIntersection( ...
                lineParameters, ...
                DeBruijnGridsApp.lineThroughPoints(xMin, yMin, xMax, yMin) ...
                );
            xTopIntersection = DeBruijnGridsApp.linesIntersection( ...
                lineParameters, ...
                DeBruijnGridsApp.lineThroughPoints(xMin, yMax, xMax, yMax) ...
                );
            yLeftIntersection = DeBruijnGridsApp.linesIntersection( ...
                lineParameters, ...
                DeBruijnGridsApp.lineThroughPoints(xMin, yMin, xMin, yMax) ...
                );
            yRightIntersection = DeBruijnGridsApp.linesIntersection( ...
                lineParameters, ...
                DeBruijnGridsApp.lineThroughPoints(xMax, yMin, xMax, yMax) ...
                );

            intersections = [];

            if ~isempty(xBottomIntersection) ...
                && xBottomIntersection(1) >= xMin ...
                && xBottomIntersection(1) < xMax
                intersections(:, end + 1) = xBottomIntersection;
            end

            if ~isempty(xTopIntersection) ...
                && xTopIntersection(1) >= xMin ...
                && xTopIntersection(1) < xMax
                intersections(:, end + 1) = xTopIntersection;
            end

            if ~isempty(yLeftIntersection) ...
                && yLeftIntersection(2) >= yMin ...
                && yLeftIntersection(2) < yMax
                intersections(:, end + 1) = yLeftIntersection;
            end

            if ~isempty(yRightIntersection) ...
                && yRightIntersection(2) >= yMin ...
                && yRightIntersection(2) < yMax
                intersections(:, end + 1) = yRightIntersection;
            end
        end


        function switchVisibilityForLines(app, groupName, isVisible)
            lineIndex = app.lineNames(groupName);
            if isVisible
                set(app.linesGroups{lineIndex}, 'Visible', 'on');
            else
                set(app.linesGroups{lineIndex}, 'Visible', 'off');
            end
        end

    end

    methods (Static)

        function intersection = linesIntersection(lineA, lineB)
            A = [lineA(1:2); lineB(1:2)];
            C = [-lineA(3); -lineB(3)];

            if det(A) == 0
                intersection = [];
            else
               intersection = A \ C;
            end
        end

        function lineParameters = lineThroughPoints(x1, y1, x2, y2)
            a = y1 - y2;
            b = x2 - x1;
            c = x1*y2 - x2*y1;

            lineParameters = [a, b, c];
        end

        function rgb = html2rgb(htmlColor)
            % htmlColor: string like '#FFD700' or 'FFD700'
            htmlColor = char(htmlColor);
            if startsWith(htmlColor, '#')
                htmlColor = htmlColor(2:end);
            end

            if numel(htmlColor) ~= 6
                error('Invalid HTML color string. Must be 6 hex digits.');
            end

            % Split into R, G, B hex pairs
            r = hex2dec(htmlColor(1:2)) / 255;
            g = hex2dec(htmlColor(3:4)) / 255;
            b = hex2dec(htmlColor(5:6)) / 255;

            rgb = [r, g, b];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XGrid = "on";
            app.UIAxes.YGrid = "on";
            axis(app.UIAxes, 'tight');
            pbaspect(app.UIAxes, [1 1 1]);

            app.offsetFields = [...
                app.OffsetA, ...
                app.OffsetB, ...
                app.OffsetC, ...
                app.OffsetD, ...
                app.OffsetE, ...
                ];

            app.lineCheckboxes = [...
                app.CheckA
                app.CheckB
                app.CheckC
                app.CheckD
                app.CheckE
            ];

            app.changeAxesLims();
            app.drawLines();
        end

        % Value changed function: OffsetA, OffsetB, OffsetC, OffsetD, 
        % ...and 1 other component
        function OffsetAValueChanged(app, event)
            app.drawLines();
        end

        % Button pushed function: RedrawButton
        function RedrawButtonPushed(app, event)
            app.changeAxesLims();
            app.drawLines();
        end

        % Value changed function: LowerX, UpperX, UpperY
        function LowerXValueChanged(app, event)
            app.changeAxesLims();           
        end

        % Value changed function: CheckA
        function CheckAValueChanged(app, event)
            value = app.CheckA.Value;
            app.switchVisibilityForLines('A', value);
        end

        % Value changed function: CheckB
        function CheckBValueChanged(app, event)
            value = app.CheckB.Value;
            app.switchVisibilityForLines('B', value);
        end

        % Value changed function: CheckC
        function CheckCValueChanged(app, event)
            value = app.CheckC.Value;
            app.switchVisibilityForLines('C', value);
        end

        % Value changed function: CheckD
        function CheckDValueChanged(app, event)
            value = app.CheckD.Value;
            app.switchVisibilityForLines('D', value);
        end

        % Value changed function: CheckE
        function CheckEValueChanged(app, event)
            value = app.CheckE.Value;
            app.switchVisibilityForLines('E', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'de Bruijn Grids')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OffsetALabel = uilabel(app.GridLayout);
            app.OffsetALabel.Text = 'Offset A';
            app.OffsetA = uieditfield(app.GridLayout, 'numeric');
            app.OffsetA.ValueChangedFcn = createCallbackFcn(app, @OffsetAValueChanged, true);
            app.OffsetA.Value = 0.2;
            app.OffsetBEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetBEditFieldLabel.Text = 'Offset B';
            app.OffsetB = uieditfield(app.GridLayout, 'numeric');
            app.OffsetB.ValueChangedFcn = createCallbackFcn(app, @OffsetAValueChanged, true);
            app.OffsetB.Value = 0.4;
            app.OffsetCEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetCEditFieldLabel.Text = 'Offset C';
            app.OffsetC = uieditfield(app.GridLayout, 'numeric');
            app.OffsetC.ValueChangedFcn = createCallbackFcn(app, @OffsetAValueChanged, true);
            app.OffsetC.Value = 0.6;
            app.OffsetDEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetDEditFieldLabel.Text = 'Offset D';
            app.OffsetD = uieditfield(app.GridLayout, 'numeric');
            app.OffsetD.ValueChangedFcn = createCallbackFcn(app, @OffsetAValueChanged, true);
            app.OffsetD.Value = 0.8;
            app.OffsetEEditFieldLabel = uilabel(app.GridLayout);
            app.OffsetEEditFieldLabel.Text = 'Offset E';
            app.OffsetE = uieditfield(app.GridLayout, 'numeric');
            app.OffsetE.ValueChangedFcn = createCallbackFcn(app, @OffsetAValueChanged, true);
            app.OffsetE.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.XLimsLabel = uilabel(app.GridLayout2);
            app.XLimsLabel.Text = 'X Lims';
            app.YLimsLabel = uilabel(app.GridLayout2);
            app.YLimsLabel.Text = 'Y Lims';
            app.LowerX = uieditfield(app.GridLayout2, 'numeric');
            app.LowerX.ValueChangedFcn = createCallbackFcn(app, @LowerXValueChanged, true);
            app.LowerX.Value = -10;
            app.UpperX = uieditfield(app.GridLayout2, 'numeric');
            app.UpperX.ValueChangedFcn = createCallbackFcn(app, @LowerXValueChanged, true);
            app.UpperX.Value = 10;
            app.LowerY = uieditfield(app.GridLayout2, 'numeric');
            app.LowerY.Value = -10;
            app.UpperY = uieditfield(app.GridLayout2, 'numeric');
            app.UpperY.ValueChangedFcn = createCallbackFcn(app, @LowerXValueChanged, true);
            app.UpperY.Value = 10;
            app.RedrawButton = uibutton(app.GridLayout, 'push');
            app.RedrawButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawButtonPushed, true);
            app.RedrawButton.Text = 'Redraw';
            app.CheckA = uicheckbox(app.GridLayout);
            app.CheckA.ValueChangedFcn = createCallbackFcn(app, @CheckAValueChanged, true);
            app.CheckA.Text = '';
            app.CheckA.Value = true;
            app.CheckB = uicheckbox(app.GridLayout);
            app.CheckB.ValueChangedFcn = createCallbackFcn(app, @CheckBValueChanged, true);
            app.CheckB.Text = '';
            app.CheckB.Value = true;
            app.CheckC = uicheckbox(app.GridLayout);
            app.CheckC.ValueChangedFcn = createCallbackFcn(app, @CheckCValueChanged, true);
            app.CheckC.Text = '';
            app.CheckC.Value = true;
            app.CheckD = uicheckbox(app.GridLayout);
            app.CheckD.ValueChangedFcn = createCallbackFcn(app, @CheckDValueChanged, true);
            app.CheckD.Text = '';
            app.CheckD.Value = true;
            app.CheckE = uicheckbox(app.GridLayout);
            app.CheckE.ValueChangedFcn = createCallbackFcn(app, @CheckEValueChanged, true);
            app.CheckE.Text = '';
            app.CheckE.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeBruijnGridsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end